classdef BioSNICAR_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RUNButton                       matlab.ui.control.Button
        Algae2ppbSliderLabel            matlab.ui.control.Label
        Algae2ppbSlider                 matlab.ui.control.Slider
        BC1ppbSliderLabel               matlab.ui.control.Label
        BC1ppbSlider                    matlab.ui.control.Slider
        Dust1ppbSliderLabel             matlab.ui.control.Label
        Dust1ppbSlider                  matlab.ui.control.Slider
        Algae1ppbSliderLabel            matlab.ui.control.Label
        Algae1ppbSlider                 matlab.ui.control.Slider
        Algae3ppbSliderLabel            matlab.ui.control.Label
        Algae3ppbSlider                 matlab.ui.control.Slider
        Algae4ppbSliderLabel            matlab.ui.control.Label
        Algae4ppbSlider                 matlab.ui.control.Slider
        Algae5ppbSliderLabel            matlab.ui.control.Label
        Algae5ppbSlider                 matlab.ui.control.Slider
        Algae6ppbSliderLabel            matlab.ui.control.Label
        Algae6ppbSlider                 matlab.ui.control.Slider
        BC2ppbSliderLabel               matlab.ui.control.Label
        BC2ppbSlider                    matlab.ui.control.Slider
        Dust2ppbSliderLabel             matlab.ui.control.Label
        Dust2ppbSlider                  matlab.ui.control.Slider
        Dust3ppbSliderLabel             matlab.ui.control.Label
        Dust3ppbSlider                  matlab.ui.control.Slider
        Dust4ppbSliderLabel             matlab.ui.control.Label
        Dust4ppbSlider                  matlab.ui.control.Slider
        IncidentFluxSwitchLabel         matlab.ui.control.Label
        IncidentFluxSwitch              matlab.ui.control.ToggleSwitch
        DeltaApproximationSwitchLabel   matlab.ui.control.Label
        DeltaApproximationSwitch        matlab.ui.control.ToggleSwitch
        CLEARButton                     matlab.ui.control.Button
        ApproximationTypeKnobLabel      matlab.ui.control.Label
        ApproximationTypeKnob           matlab.ui.control.DiscreteKnob
        CosineofsolarzenithTextAreaLabel  matlab.ui.control.Label
        CosineofsolarzenithTextArea     matlab.ui.control.TextArea
        LayerThicknessesmTextAreaLabel  matlab.ui.control.Label
        LayerThicknessesmTextArea       matlab.ui.control.TextArea
        LayerDensitieskgm3TextAreaLabel  matlab.ui.control.Label
        LayerDensitieskgm3TextArea      matlab.ui.control.TextArea
        LayerGrainSizeumTextAreaLabel   matlab.ui.control.Label
        LayerGrainSizeumTextArea        matlab.ui.control.TextArea
        AlbedoofunderlyingsurfaceTextAreaLabel  matlab.ui.control.Label
        AlbedoofunderlyingsurfaceTextArea  matlab.ui.control.TextArea
        ExportDatasetButton             matlab.ui.control.Button
        OutputLocationTextAreaLabel     matlab.ui.control.Label
        OutputLocationTextArea          matlab.ui.control.TextArea
    end


    properties (Access = private)
        albedo_spectral % Description
    end


    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            % FOR WEB DEPLOYMENT INSTRUCTIONS: https://uk.mathworks.com/solutions/desktop-web-deployment.html
            
            % VALUES FROM SLIDER CALLBACKS
            DIRECT = str2num(app.IncidentFluxSwitch.Value);
            DELTA = str2num(app.DeltaApproximationSwitch.Value);
            APRX_TYP = str2num(app.ApproximationTypeKnob.Value);
            coszen = str2num(char(app.CosineofsolarzenithTextArea.Value))
                       
            % coszen is only necessary if DIRECT ==1, but some number needs to be provided for the code to run, so default to 42
            if DIRECT == 0
                coszen = 42;
            end
            
            dz = [str2num(char(app.LayerThicknessesmTextArea.Value))]
            % if no values provided for dz, use 5 default layers
            if length(dz) ==0
                dz = [0.01,0.05,0.05,0.05,0.05]
            end

            rho = [str2num(char(app.LayerDensitieskgm3TextArea.Value))]
            % if no values provided for rho, use 5 default densities
            if length(rho) ==0
                rho = [200,200,200,200,200]
            end
            
            rds = [str2num(char(app.LayerGrainSizeumTextArea.Value))]
            % if no values provided for dz, use 5 default radii
            if length(rds) ==0
                rds = [500,500,500,500,500]
            end
                        
            R_sfc = str2num(char(app.AlbedoofunderlyingsurfaceTextArea.Value));
            % if no values provided for R_sfc, use default
            if length(R_sfc) ==0
                R_sfc = 0.25
            end    
            
            BIO1 = app.Algae1ppbSlider.Value;
            BIO2 = app.Algae2ppbSlider.Value;
            BIO3 = app.Algae3ppbSlider.Value;
            BIO4 = app.Algae4ppbSlider.Value;
            BIO5 = app.Algae5ppbSlider.Value;
            BIO6 = app.Algae6ppbSlider.Value;
            
            BC1 = app.BC1ppbSlider.Value;
            BC2 = app.BC2ppbSlider.Value;
            DUST1 = app.Dust1ppbSlider.Value;
            DUST2 = app.Dust2ppbSlider.Value;
            DUST3 = app.Dust3ppbSlider.Value;
            DUST4 = app.Dust4ppbSlider.Value;
            
            % CALL TO SNICAR MODEL WITH VALUES FROM SLIDERS
            [alb_slr,albedo_spectral] = SNICAR_Function_APP(1,APRX_TYP,DIRECT,DELTA,coszen,R_sfc, ...
            dz, rho, rds,BC1,BC2,DUST1,DUST2,DUST3,DUST4,0,BIO1,BIO2,BIO3,BIO4,BIO5,BIO6,0,0,0);
            wvl = 0.305:0.01:5;
            
            app.albedo_spectral = albedo_spectral;
            
            % PLOT
            handles.alb_slr = alb_slr;
            handles.albedo_spectral = albedo_spectral;
            handles.wvl = wvl;
            app.UIAxes.XLim = [0.305 5];
            app.UIAxes.YLim = [0 1];
            plot(app.UIAxes,handles.wvl,handles.albedo_spectral,'LineWidth',2);
            hold(app.UIAxes,'on');
            
            
        end

        % Value changed function: Algae2ppbSlider
        function Algae2ppbSliderValueChanged(app, event)
            
           BIO2 = app.Algae2ppbSlider.Value;
            
        end

        % Callback function
        function AlgaeppbSliderValueChanging(app, event)
         
        end

        % Value changed function: BC1ppbSlider
        function BC1ppbSliderValueChanged(app, event)
            
            BC1 = app.BC1ppbSlider.Value;
            
        end

        % Value changed function: Dust1ppbSlider
        function Dust1ppbSliderValueChanged(app, event)
            
            DST1 = app.Dust1ppbSlider.Value;
            
        end

        % Value changed function: Algae1ppbSlider
        function Algae1ppbSliderValueChanged(app, event)
            
            BIO1 = app.Algae1ppbSlider.Value;
            
        end

        % Value changed function: Algae3ppbSlider
        function Algae3ppbSliderValueChanged(app, event)
            
            BIO3 = app.Algae3ppbSlider.Value;
            
        end

        % Value changed function: Algae4ppbSlider
        function Algae4ppbSliderValueChanged(app, event)
            
            BIO4 = app.Algae4ppbSlider.Value;
            
        end

        % Value changed function: Algae5ppbSlider
        function Algae5ppbSliderValueChanged(app, event)
            
            BIO5 = app.Algae5ppbSlider.Value;
            
        end

        % Value changed function: Algae6ppbSlider
        function Algae6ppbSliderValueChanged(app, event)
            
            BIO6 = app.Algae6ppbSlider.Value;
            
        end

        % Value changed function: BC2ppbSlider
        function BC2ppbSliderValueChanged(app, event)
            
            BC2 = app.BC2ppbSlider.Value;
            
        end

        % Value changed function: Dust2ppbSlider
        function Dust2ppbSliderValueChanged(app, event)
            
            DST2 = app.Dust2ppbSlider.Value;
            
        end

        % Value changed function: Dust3ppbSlider
        function Dust3ppbSliderValueChanged(app, event)
           
            DST3 = app.Dust3ppbSlider.Value;
            
        end

        % Value changed function: Dust4ppbSlider
        function Dust4ppbSliderValueChanged(app, event)
            
            DST4 = app.Dust4ppbSlider.Value;
            
        end

        % Value changed function: IncidentFluxSwitch
        function IncidentFluxSwitchValueChanged(app, event)
            
            DIRECT = app.IncidentFluxSwitch.Value;
            
        end

        % Value changed function: DeltaApproximationSwitch
        function DeltaApproximationSwitchValueChanged(app, event)
            
            DELTA = app.DeltaApproximationSwitch.Value;
            
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Value changed function: ApproximationTypeKnob
        function ApproximationTypeKnobValueChanged(app, event)
            
            APRX_TYP = app.ApproximationTypeKnob.Value;
            
        end

        % Value changed function: CosineofsolarzenithTextArea
        function CosineofsolarzenithTextAreaValueChanged(app, event)
            
            coszen = app.CosineofsolarzenithTextArea.Value
            
        end

        % Value changed function: LayerThicknessesmTextArea
        function LayerThicknessesmTextAreaValueChanged(app, event)
            
            dz = app.LayerThicknessesmTextArea.Value;
            
        end

        % Value changed function: LayerDensitieskgm3TextArea
        function LayerDensitieskgm3TextAreaValueChanged(app, event)
            
            rho = app.LayerDensitieskgm3TextArea.Value;
            
        end

        % Value changed function: LayerGrainSizeumTextArea
        function LayerGrainSizeumTextAreaValueChanged(app, event)
            
            rds = app.LayerGrainSizeumTextArea.Value;
            
        end

        % Value changed function: AlbedoofunderlyingsurfaceTextArea
        function AlbedoofunderlyingsurfaceTextAreaValueChanged(app, event)
            
            R_sfc = app.AlbedoofunderlyingsurfaceTextArea.Value;
            
        end

        % Button pushed function: ExportDatasetButton
        function ExportDatasetButtonPushed(app, event)
            csvwrite(char(app.OutputLocationTextArea.Value),app.albedo_spectral)
        end

        % Value changed function: OutputLocationTextArea
        function OutputLocationTextAreaValueChanged(app, event)
            
            savefile = app.OutputLocationTextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Snow/Ice Spectral Albedo')
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Albedo')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Algae2ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae2ppbSliderLabel.Text = 'Algae 2 (ppb)';
            app.Algae2ppbSlider = uislider(app.UIFigure);
            app.Algae2ppbSlider.Limits = [0 1000000];
            app.Algae2ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae2ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae2ppbSliderValueChanged, true);
            app.BC1ppbSliderLabel = uilabel(app.UIFigure);
            app.BC1ppbSliderLabel.Text = 'BC1 (ppb) ';
            app.BC1ppbSlider = uislider(app.UIFigure);
            app.BC1ppbSlider.Limits = [0 30000];
            app.BC1ppbSlider.ValueChangedFcn = createCallbackFcn(app, @BC1ppbSliderValueChanged, true);
            app.Dust1ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust1ppbSliderLabel.Text = 'Dust 1 (ppb)';
            app.Dust1ppbSlider = uislider(app.UIFigure);
            app.Dust1ppbSlider.Limits = [0 1000000];
            app.Dust1ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust1ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust1ppbSliderValueChanged, true);
            app.Algae1ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae1ppbSliderLabel.Text = 'Algae 1 (ppb)';
            app.Algae1ppbSlider = uislider(app.UIFigure);
            app.Algae1ppbSlider.Limits = [0 1000000];
            app.Algae1ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae1ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae1ppbSliderValueChanged, true);
            app.Algae3ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae3ppbSliderLabel.Text = 'Algae 3 (ppb)';
            app.Algae3ppbSlider = uislider(app.UIFigure);
            app.Algae3ppbSlider.Limits = [0 1000000];
            app.Algae3ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae3ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae3ppbSliderValueChanged, true);
            app.Algae4ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae4ppbSliderLabel.Text = 'Algae 4 (ppb)';
            app.Algae4ppbSlider = uislider(app.UIFigure);
            app.Algae4ppbSlider.Limits = [0 1000000];
            app.Algae4ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae4ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae4ppbSliderValueChanged, true);
            app.Algae5ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae5ppbSliderLabel.Text = 'Algae 5 (ppb)';
            app.Algae5ppbSlider = uislider(app.UIFigure);
            app.Algae5ppbSlider.Limits = [0 1000000];
            app.Algae5ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae5ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae5ppbSliderValueChanged, true);
            app.Algae6ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae6ppbSliderLabel.Text = 'Algae 6 (ppb)';
            app.Algae6ppbSlider = uislider(app.UIFigure);
            app.Algae6ppbSlider.Limits = [0 1000000];
            app.Algae6ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae6ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae6ppbSliderValueChanged, true);
            app.BC2ppbSliderLabel = uilabel(app.UIFigure);
            app.BC2ppbSliderLabel.Text = 'BC2 (ppb) ';
            app.BC2ppbSlider = uislider(app.UIFigure);
            app.BC2ppbSlider.Limits = [0 30000];
            app.BC2ppbSlider.ValueChangedFcn = createCallbackFcn(app, @BC2ppbSliderValueChanged, true);
            app.Dust2ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust2ppbSliderLabel.Text = 'Dust 2 (ppb)';
            app.Dust2ppbSlider = uislider(app.UIFigure);
            app.Dust2ppbSlider.Limits = [0 1000000];
            app.Dust2ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust2ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust2ppbSliderValueChanged, true);
            app.Dust3ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust3ppbSliderLabel.Text = 'Dust 3 (ppb)';
            app.Dust3ppbSlider = uislider(app.UIFigure);
            app.Dust3ppbSlider.Limits = [0 1000000];
            app.Dust3ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust3ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust3ppbSliderValueChanged, true);
            app.Dust4ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust4ppbSliderLabel.Text = 'Dust 4 (ppb)';
            app.Dust4ppbSlider = uislider(app.UIFigure);
            app.Dust4ppbSlider.Limits = [0 1000000];
            app.Dust4ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust4ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust4ppbSliderValueChanged, true);
            app.IncidentFluxSwitchLabel = uilabel(app.UIFigure);
            app.IncidentFluxSwitchLabel.Text = 'Incident Flux';
            app.IncidentFluxSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IncidentFluxSwitch.Items = {'DIFFUSE', 'DIRECT'};
            app.IncidentFluxSwitch.ItemsData = {'0', '1'};
            app.IncidentFluxSwitch.ValueChangedFcn = createCallbackFcn(app, @IncidentFluxSwitchValueChanged, true);
            app.IncidentFluxSwitch.Value = '0';
            app.DeltaApproximationSwitchLabel = uilabel(app.UIFigure);
            app.DeltaApproximationSwitchLabel.Text = 'Delta Approximation';
            app.DeltaApproximationSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DeltaApproximationSwitch.Items = {'OFF', 'ON'};
            app.DeltaApproximationSwitch.ItemsData = {'0', '1'};
            app.DeltaApproximationSwitch.ValueChangedFcn = createCallbackFcn(app, @DeltaApproximationSwitchValueChanged, true);
            app.DeltaApproximationSwitch.Value = '0';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ApproximationTypeKnobLabel = uilabel(app.UIFigure);
            app.ApproximationTypeKnobLabel.Text = 'Approximation Type';
            app.ApproximationTypeKnob = uiknob(app.UIFigure, 'discrete');
            app.ApproximationTypeKnob.Items = {'Hemispheric Mean', 'Quadrature', 'Eddington'};
            app.ApproximationTypeKnob.ItemsData = {'1', '2', '3'};
            app.ApproximationTypeKnob.ValueChangedFcn = createCallbackFcn(app, @ApproximationTypeKnobValueChanged, true);
            app.ApproximationTypeKnob.Value = '3';
            app.CosineofsolarzenithTextAreaLabel = uilabel(app.UIFigure);
            app.CosineofsolarzenithTextAreaLabel.Text = 'Cosine of solar zenith';
            app.CosineofsolarzenithTextArea = uitextarea(app.UIFigure);
            app.CosineofsolarzenithTextArea.ValueChangedFcn = createCallbackFcn(app, @CosineofsolarzenithTextAreaValueChanged, true);
            app.LayerThicknessesmTextAreaLabel = uilabel(app.UIFigure);
            app.LayerThicknessesmTextAreaLabel.Text = 'Layer Thicknesses (m)';
            app.LayerThicknessesmTextArea = uitextarea(app.UIFigure);
            app.LayerThicknessesmTextArea.ValueChangedFcn = createCallbackFcn(app, @LayerThicknessesmTextAreaValueChanged, true);
            app.LayerDensitieskgm3TextAreaLabel = uilabel(app.UIFigure);
            app.LayerDensitieskgm3TextAreaLabel.Text = 'Layer Densities (kg / m3)';
            app.LayerDensitieskgm3TextArea = uitextarea(app.UIFigure);
            app.LayerDensitieskgm3TextArea.ValueChangedFcn = createCallbackFcn(app, @LayerDensitieskgm3TextAreaValueChanged, true);
            app.LayerGrainSizeumTextAreaLabel = uilabel(app.UIFigure);
            app.LayerGrainSizeumTextAreaLabel.Text = 'Layer Grain Size (um)';
            app.LayerGrainSizeumTextArea = uitextarea(app.UIFigure);
            app.LayerGrainSizeumTextArea.ValueChangedFcn = createCallbackFcn(app, @LayerGrainSizeumTextAreaValueChanged, true);
            app.AlbedoofunderlyingsurfaceTextAreaLabel = uilabel(app.UIFigure);
            app.AlbedoofunderlyingsurfaceTextAreaLabel.Text = 'Albedo of underlying surface';
            app.AlbedoofunderlyingsurfaceTextArea = uitextarea(app.UIFigure);
            app.AlbedoofunderlyingsurfaceTextArea.ValueChangedFcn = createCallbackFcn(app, @AlbedoofunderlyingsurfaceTextAreaValueChanged, true);
            app.ExportDatasetButton = uibutton(app.UIFigure, 'push');
            app.ExportDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatasetButtonPushed, true);
            app.ExportDatasetButton.Text = 'Export Dataset';
            app.OutputLocationTextAreaLabel = uilabel(app.UIFigure);
            app.OutputLocationTextAreaLabel.Text = 'Output Location';
            app.OutputLocationTextArea = uitextarea(app.UIFigure);
            app.OutputLocationTextArea.ValueChangedFcn = createCallbackFcn(app, @OutputLocationTextAreaValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BioSNICAR_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end